classdef Cluster_Plotting_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusterPlottingFigure          matlab.ui.Figure
        DFC_FileLabel                  matlab.ui.control.Label
        DFCFileLabel                   matlab.ui.control.Label
        clearDFCFileBtn                matlab.ui.control.Button
        DFCFileSelectionLabel          matlab.ui.control.Label
        State12Label                   matlab.ui.control.Label
        State11Label                   matlab.ui.control.Label
        State10Label                   matlab.ui.control.Label
        State9Label                    matlab.ui.control.Label
        State8Label                    matlab.ui.control.Label
        State7Label                    matlab.ui.control.Label
        State6Label                    matlab.ui.control.Label
        State5Label                    matlab.ui.control.Label
        State4Label                    matlab.ui.control.Label
        State3Label                    matlab.ui.control.Label
        State2Label                    matlab.ui.control.Label
        State1Label                    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ThebestKLabel                  matlab.ui.control.Label
        SaveAsButton                   matlab.ui.control.Button
        PlottingLabel                  matlab.ui.control.Label
        DistancemethodDropDown_1       matlab.ui.control.DropDown
        DistancemethodDropDown_2Label  matlab.ui.control.Label
        CLusterButton                  matlab.ui.control.Button
        DistancemethodDropDown_2       matlab.ui.control.DropDown
        DistancemethodDropDownLabel    matlab.ui.control.Label
        KEditField                     matlab.ui.control.EditField
        PrefixLabel_2                  matlab.ui.control.Label
        KmeansClusterLabel             matlab.ui.control.Label
        BestKOptionalLabel             matlab.ui.control.Label
        titleLabel                     matlab.ui.control.Label
        selectDFCFileBtn               matlab.ui.control.Button
        BestKBtn                       matlab.ui.control.Button
        UIAxes3_3                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes3_1                      matlab.ui.control.UIAxes
        Image_right                    matlab.ui.control.UIAxes
        Image_left                     matlab.ui.control.UIAxes
        UIAxes2_4                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes2_1                      matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mainApp % 主界面对象
        allState
        stateTransition
        K
        totalPage
        curPage
        allFileName
        info
        dFC_result_3D
        mean
        median
        variability
        dMethod
        cbar = struct('UIAxes3_2', '', 'UIAxes3_3', '');
    end
    
    methods (Access = private)
        
        function Image_rightButtonDown(app, src, event)
            if app.curPage < app.totalPage % 判断是否小于最大页， 不符合则什么也不做
                % 1. 修改curPage的页码
                app.curPage = app.curPage + 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 2. 生成上一页的4状态
                % 2.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                NDN_plottingStates(app, char(app.allFileName))
            end
        end
        function Image_leftButtonDown(app, src, event)
            if app.curPage > 1 % 判断是否大于第一页， 不符合则什么也不做
                % 1. 修改curPage的页码
                app.curPage = app.curPage - 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 2. 生成上一页的4状态
                % 2.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                % 2.2 生成状态
                NDN_plottingStates(app, char(app.allFileName))
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp = caller;
            assignin('base', ['Cluster_Plotting' '_app'], app);
            try
                app.DFC_FileLabel.WordWrap = 'on';
            catch ME
                if ME.message
                    msg = ME.message;
                else
                    msg = ['The MATLAB version is old. FileName could not wrap'];
                end
                disp(msg)
            end
            
            % 给app属性赋初始值
            app.curPage = 1;
            
        end

        % Close request function: ClusterPlottingFigure
        function ClusterPlottingFigureCloseRequest(app, event)
            app.mainApp.ClusteringPlottingButton.Enable = 'on';
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: selectDFCFileBtn
        function selectDFCFileBtnPushed(app, event)
            app.ClusterPlottingFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            [filename, pathname] = uigetfile('*.*','Please select a DFC Res mat file');
            if filename ~= 0
                if~isequal(filename(end - 7 : end), '_all.mat')
                    app.mainApp.NaDyNetUIFigure.Visible = 'on';
                    app.ClusterPlottingFigure.Visible = 'on';
                    pause(0.1)
                    msg = ['The file name must be end with "_all.mat", generated by NaDyNet Step 2, but the file: ' filename ' you select is not accord with this rule'];
                    title = 'Error';
                    msgbox(msg, title, 'error');
                    return
                end
                
                app.clearDFCFileBtnButtonPushed(app)
                matAddress = fullfile(pathname, filename);
                result = importdata(matAddress);
                app.allFileName = string(matAddress);

                app.dFC_result_3D = result.dFC_result_3D;
                app.info.TR = result.TR;
                app.info.method = result.method;
                app.info.N_sub = result.N_sub;
                app.info.N_roi = result.N_roi;
                app.info.N_roi = result.N_roi;
                app.info.inputFileAddr = matAddress;
                if isfield(result, 'ISA_type')
                    app.info.ISA_type = result.ISA_type;
                end
                % clear

                %
                try
                    info = sprintf("Method:  %s,   TR:  %d,   Subjects:  %d,   ROIs:  %d", result.method, result.TR, result.N_sub, result.N_roi);
                catch ME
                    disp(ME.message)
                    info = "";
                end

                app.DFC_FileLabel.Text = 'FileName:  ' + app.allFileName + newline + newline + newline + info;
            end
            
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ClusterPlottingFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: clearDFCFileBtn
        function clearDFCFileBtnButtonPushed(app, event)
            app.DFC_FileLabel.Text = "";
            app.allFileName = "";
            app.ThebestKLabel.Text = 'The best K:  ';
            app.ThebestKLabel.Visible = "off";

            app.Label.Visible = 'off';
            app.Image_left.Visible = 'off';
            app.Image_right.Visible = 'off';
            app.SaveAsButton.Enable = 'off';
            app.mean = struct(); 
            app.median = struct();
            app.variability = struct();
            app.info = struct();
            app.stateTransition = [];
            app.allState = [];

            for i = 1:12
                app.(['State' num2str(i) 'Label']).Visible = 'off';
            end

            app.UIAxes.Visible = "off";
            cla(app.UIAxes);
                
            % 隐藏全部状态
            for i = 1: 4
                app.(['UIAxes2_' num2str(i)]).Visible = "off";
                cla(app.(['UIAxes2_' num2str(i)]));
                app.(['UIAxes2_' num2str(i)]).Title.String = "";
            end
            % 隐藏全部图像
            for i = 1: 3
                app.(['UIAxes3_' num2str(i)]).Visible = "off";
                app.(['UIAxes3_' num2str(i)]).XAxis.Visible = "off";
                app.(['UIAxes3_' num2str(i)]).YAxis.Visible = "off";
                cla(app.(['UIAxes3_' num2str(i)]));
                app.(['UIAxes3_' num2str(i)]).Title.String = "";
            end

    
            if(~isempty(app.cbar.UIAxes3_2))
                app.cbar.UIAxes3_2.Visible = 'off';
            end

            if(~isempty(app.cbar.UIAxes3_3))
                app.cbar.UIAxes3_3.Visible = 'off';
            end


            cla(app.Image_right)
            cla(app.Image_left)

        end

        % Button pushed function: BestKBtn
        function BestKBtnPushed(app, event)
            if isempty(app.allFileName)
                msg = ['No DFC file is selected currently, Please assign a new one.'];
                title =  ['Please select a DFC Result. ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            %% turn off
            app.DistancemethodDropDown_1.Enable = 'off';
            app.selectDFCFileBtn.Enable = 'off';
            app.BestKBtn.Enable = 'off';
            %             pause(10)
            try
                inputFile = app.allFileName;
                dMethod = app.DistancemethodDropDown_1.Value;
                app.dMethod = dMethod;
                
                app.K = NDN_bestK(inputFile, dMethod);
                app.ThebestKLabel.Text = ['The best K:  ' num2str(app.K)];
                if app.K < 13 && app.K > 0
                    app.KEditField.Value = num2str(app.K);
                    app.totalPage = ceil(app.K / 4);
                end
                app.ThebestKLabel.Visible = "on";
                
                %% turn on
                app.DistancemethodDropDown_1.Enable = 'on';
                app.selectDFCFileBtn.Enable = 'on';
                app.BestKBtn.Enable = 'on';
            catch ME
                %% turn on
                app.DistancemethodDropDown_1.Enable = 'on';
                app.selectDFCFileBtn.Enable = 'on';
                app.BestKBtn.Enable = 'on';
                
                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is a error in computing kmeans ' ];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
            end
            
            
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            
            value = str2double(app.KEditField.Value);
            if value < 13 && value > 0
                app.K = value;
                
            else
                app.KEditField.Value = '';
                msg = 'The value of K should be in the range of 【1, 12】';
                title = 'Error Input';
                msgbox(msg, title, 'warning');
            end


        end

        % Button pushed function: CLusterButton
        function CLusterButtonPushed(app, event)
            if isequal(app.allFileName, "")
                msg = ['The value of "DFC res Result" should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for " DFC res Result" ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            if isempty(app.KEditField.Value)
                msg = ['The value of "K" should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for "K" ' ];
                msgbox(msg, title, 'warn')
                return
            end
            
            %% turn off
            app.DistancemethodDropDown_2.Enable = 'off';
            app.clearDFCFileBtn.Enable = 'off';
            app.selectDFCFileBtn.Enable = 'off';
            app.CLusterButton.Enable = 'off';
            app.KEditField.Enable = 'off';
            
            try
                inputFile = char(app.allFileName);
                dMethod = app.DistancemethodDropDown_1.Value;
                
                for i = 1:12
                    app.(['State' num2str(i) 'Label']).Visible = 'off';
                end
                
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                
                
                [app.allState, app.stateTransition] = NDN_kmeans(inputFile, dMethod, app.K);
                app.totalPage = ceil(app.K / 4);
                app.curPage = 1;
                NDN_plotting(app, char(app.allFileName))
                
                %% turn on
                app.DistancemethodDropDown_2.Enable = 'on';
                app.clearDFCFileBtn.Enable = 'on';
                app.selectDFCFileBtn.Enable = 'on';
                app.CLusterButton.Enable = 'on';
                app.KEditField.Enable = 'on';

                app.Label.Visible = 'on';
                app.Image_left.Visible = 'on';
                app.Image_right.Visible = 'on';
                app.SaveAsButton.Enable = 'on';
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                
                % 显示换页按钮
                rootPath = which('NDN');
                rootPath = fileparts(rootPath);
                leftImg = [rootPath filesep 'assets\left.png'];
                rightImg = [rootPath filesep 'assets\right.png'];
                [imLeft, ~, alphaL] = imread(leftImg);
                [imRight, ~, alphaR] = imread(rightImg);


                % 将透明区域替换为白色
                if ~isempty(alphaL)
                    % 创建一个白色背景
                    whiteBackground = 240 * ones(size(imLeft, 1), size(imLeft, 2), 3, 'uint8');

                    % 将透明区域替换为白色
                    for i = 1:3
                        channel = imLeft(:, :, i);
                        temp = whiteBackground(:, :, i);
                        channel(alphaL == 0) = temp(alphaL == 0);
                        imLeft(:, :, i) = channel;
                    end
                end

                % 将透明区域替换为白色
                if ~isempty(alphaR)
                    % 创建一个白色背景
                    whiteBackground = 240 * ones(size(imRight, 1), size(imRight, 2), 3, 'uint8');

                    % 将透明区域替换为白色
                    for i = 1:3
                        channel = imRight(:, :, i);
                        temp = whiteBackground(:, :, i);
                        channel(alphaR == 0) = temp(alphaR == 0);
                        imRight(:, :, i) = channel;
                    end
                end
            
                app.Image_left.XLim = [0.5, 30 + 0.5];
                app.Image_left.YLim = [0.5, 30 + 0.5];
                app.Image_right.XLim = [0.5, 30 + 0.5];
                app.Image_right.YLim = [0.5, 30 + 0.5];
                app.Image_left.XColor = 'none';
                app.Image_left.YColor = 'none';
                app.Image_right.XColor = 'none';
                app.Image_right.YColor = 'none';
                LImage = image(imLeft, 'Parent', app.Image_left);
                RLImage = imshow(imRight, 'Parent', app.Image_right);
                axis(app.Image_left, 'image'); 
                axis(app.Image_right, 'image'); 
                app.Image_right.Visible = "on";
                app.Image_left.Visible = "on";

                NDN_getSub_M_DFC(app)

                % 添加回调行为
                LImage.ButtonDownFcn = @app.Image_leftButtonDown;
                RLImage.ButtonDownFcn = @app.Image_rightButtonDown;
            catch ME
                %% turn on
                app.DistancemethodDropDown_2.Enable = 'on';
                app.clearDFCFileBtn.Enable = 'on';
                app.CLusterButton.Enable = 'on';
                app.selectDFCFileBtn.Enable = 'on';
                app.KEditField.Enable = 'on';
                
                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing kmeans'];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
            end
            
        end

        % Callback function
        function Image_leftClicked(app, event)
            if app.curPage > 1
                % 1. 修改curPage的页码
                app.curPage = app.curPage - 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                %                 app.Image_right.Enable = 'on';
                
                % 2. 判断curPage == 1,等于的话那就要Enable = 'off'
                %                 if app.curPage == 1
                %                     app.Image_left.Enable = 'off';
                %                 end
                
                % 3. 生成上一页的4状态
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                % 3.2 生成状态
                NDN_plottingStates(app, char(app.allFileName))
            end
        end

        % Callback function
        function Image_rightClicked(app, event)
            if app.curPage < app.totalPage
                % 1. 修改curPage的页码
                app.curPage = app.curPage + 1;
                app.Label.Text = [num2str(app.curPage) ' / ' num2str(app.totalPage)];
                %                 app.Image_left.Enable = 'on';
                
                % 2. 判断curPage == app.totalPage,等于的话那就要Enable = 'off'
                %                 if app.curPage == app.totalPage
                %                     app.Image_right.Enable = 'off';
                %                 end
                
                % 3. 生成下一页的4状态
                % 3.1 生成之前隐藏全部状态
                for i = 1: 4
                    cla(app.(['UIAxes2_' num2str(i)]));
                    app.(['UIAxes2_' num2str(i)]).Title.String = "";
                end
                NDN_plottingStates(app, char(app.allFileName))
            end
            
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.ClusterPlottingFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            [savedDir] = uigetdir('*.*', 'Please select a saved directory');
            if  savedDir ~= 0
                try
                    NDN_savePlot_cluster(app, char(app.allFileName), savedDir)
                catch ME
                    if ME.message
                        msg = ME.message;
                    else
                        msg = ['There is an error in saving'];
                    end
                    title = 'Error';
                    msgbox(msg, title, 'error');
                end
            end
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ClusterPlottingFigure.Visible = 'on';%防止获取路径之后界面最小化
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusterPlottingFigure = uifigure('Visible', 'off');
            colormap(app.ClusterPlottingFigure, 'jet');
            app.ClusterPlottingFigure.Name = 'Cluster & Plotting';
            app.ClusterPlottingFigure.CloseRequestFcn = createCallbackFcn(app, @ClusterPlottingFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            colormap(app.UIAxes, 'jet')
            app.UIAxes.Visible = 'off';
            app.UIAxes2_1 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_1.XTick = [];
            app.UIAxes2_1.XTickLabel = '';
            app.UIAxes2_1.YTick = [];
            app.UIAxes2_1.YTickLabel = '';
            colormap(app.UIAxes2_1, 'jet')
            app.UIAxes2_1.Visible = 'off';
            app.UIAxes2_3 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.XTickLabel = '';
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.YTickLabel = '';
            colormap(app.UIAxes2_3, 'jet')
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            colormap(app.UIAxes2_2, 'jet')
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2_4 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            colormap(app.UIAxes2_4, 'jet')
            app.UIAxes2_4.Visible = 'off';
            app.Image_left = uiaxes(app.ClusterPlottingFigure);
            app.Image_left.XTick = [];
            app.Image_left.XTickLabel = '';
            app.Image_left.YTick = [];
            app.Image_left.YTickLabel = '';
            colormap(app.Image_left, 'jet')
            app.Image_left.Visible = 'off';
            app.Image_right = uiaxes(app.ClusterPlottingFigure);
            app.Image_right.XTick = [];
            app.Image_right.XTickLabel = '';
            app.Image_right.YTick = [];
            app.Image_right.YTickLabel = '';
            colormap(app.Image_right, 'jet')
            app.Image_right.Visible = 'off';
            app.UIAxes3_1 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes3_1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3_1.YTick = [0 0.5 1];
            colormap(app.UIAxes3_1, 'jet')
            app.UIAxes3_1.Visible = 'off';
            app.UIAxes3_2 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            colormap(app.UIAxes3_2, 'jet')
            app.UIAxes3_2.Visible = 'off';
            app.UIAxes3_3 = uiaxes(app.ClusterPlottingFigure);
            app.UIAxes3_3.XTick = [];
            app.UIAxes3_3.YTick = [];
            colormap(app.UIAxes3_3, 'jet')
            app.UIAxes3_3.Visible = 'off';
            app.BestKBtn = uibutton(app.ClusterPlottingFigure, 'push');
            app.BestKBtn.ButtonPushedFcn = createCallbackFcn(app, @BestKBtnPushed, true);
            app.BestKBtn.Text = 'Best K';
            app.selectDFCFileBtn = uibutton(app.ClusterPlottingFigure, 'push');
            app.selectDFCFileBtn.ButtonPushedFcn = createCallbackFcn(app, @selectDFCFileBtnPushed, true);
            app.selectDFCFileBtn.Text = 'Load';
            app.titleLabel = uilabel(app.ClusterPlottingFigure);
            app.titleLabel.Text = 'Clustering & Plotting';
            app.BestKOptionalLabel = uilabel(app.ClusterPlottingFigure);
            app.BestKOptionalLabel.Text = ' 2. Best K (Optional)';
            app.KmeansClusterLabel = uilabel(app.ClusterPlottingFigure);
            app.KmeansClusterLabel.Text = ' 3. Kmeans Cluster';
            app.PrefixLabel_2 = uilabel(app.ClusterPlottingFigure);
            app.PrefixLabel_2.Text = 'K:';
            app.KEditField = uieditfield(app.ClusterPlottingFigure, 'text');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.DistancemethodDropDownLabel = uilabel(app.ClusterPlottingFigure);
            app.DistancemethodDropDownLabel.Text = 'Distance method:';
            app.DistancemethodDropDown_2 = uidropdown(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_2.Items = {'cityblock', 'cosine', 'sqeuclidean', 'correlation', 'hamming'};
            app.DistancemethodDropDown_2.Value = 'cityblock';
            app.CLusterButton = uibutton(app.ClusterPlottingFigure, 'push');
            app.CLusterButton.ButtonPushedFcn = createCallbackFcn(app, @CLusterButtonPushed, true);
            app.CLusterButton.Text = 'Cluster';
            app.DistancemethodDropDown_2Label = uilabel(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_2Label.Text = 'Distance method:';
            app.DistancemethodDropDown_1 = uidropdown(app.ClusterPlottingFigure);
            app.DistancemethodDropDown_1.Items = {'cityblock', 'cosine', 'sqeuclidean', 'correlation', 'hamming'};
            app.DistancemethodDropDown_1.Value = 'cityblock';
            app.PlottingLabel = uilabel(app.ClusterPlottingFigure);
            app.PlottingLabel.Text = ' 3. Plotting';
            app.SaveAsButton = uibutton(app.ClusterPlottingFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Enable = 'off';
            app.SaveAsButton.Text = 'Save As';
            app.ThebestKLabel = uilabel(app.ClusterPlottingFigure);
            app.ThebestKLabel.Visible = 'off';
            app.ThebestKLabel.Text = 'The best K:  ';
            app.Label = uilabel(app.ClusterPlottingFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '1/1';
            app.State1Label = uilabel(app.ClusterPlottingFigure);
            app.State1Label.Visible = 'off';
            app.State1Label.Text = '● State 1';
            app.State2Label = uilabel(app.ClusterPlottingFigure);
            app.State2Label.Visible = 'off';
            app.State2Label.Text = '● State 2';
            app.State3Label = uilabel(app.ClusterPlottingFigure);
            app.State3Label.Visible = 'off';
            app.State3Label.Text = '● State 3';
            app.State4Label = uilabel(app.ClusterPlottingFigure);
            app.State4Label.Visible = 'off';
            app.State4Label.Text = '● State 4';
            app.State5Label = uilabel(app.ClusterPlottingFigure);
            app.State5Label.Visible = 'off';
            app.State5Label.Text = '● State 5';
            app.State6Label = uilabel(app.ClusterPlottingFigure);
            app.State6Label.Visible = 'off';
            app.State6Label.Text = '● State 6';
            app.State7Label = uilabel(app.ClusterPlottingFigure);
            app.State7Label.Visible = 'off';
            app.State7Label.Text = '● State 7';
            app.State8Label = uilabel(app.ClusterPlottingFigure);
            app.State8Label.Visible = 'off';
            app.State8Label.Text = '● State 8';
            app.State9Label = uilabel(app.ClusterPlottingFigure);
            app.State9Label.Visible = 'off';
            app.State9Label.Text = '● State 9';
            app.State10Label = uilabel(app.ClusterPlottingFigure);
            app.State10Label.Visible = 'off';
            app.State10Label.Text = '● State 10';
            app.State11Label = uilabel(app.ClusterPlottingFigure);
            app.State11Label.Visible = 'off';
            app.State11Label.Text = '● State 11';
            app.State12Label = uilabel(app.ClusterPlottingFigure);
            app.State12Label.Visible = 'off';
            app.State12Label.Text = '● State 12';
            app.DFCFileSelectionLabel = uilabel(app.ClusterPlottingFigure);
            app.DFCFileSelectionLabel.Text = ' 1. DFC File Selection';
            app.clearDFCFileBtn = uibutton(app.ClusterPlottingFigure, 'push');
            app.clearDFCFileBtn.ButtonPushedFcn = createCallbackFcn(app, @clearDFCFileBtnButtonPushed, true);
            app.clearDFCFileBtn.Text = 'Clear';
            app.DFCFileLabel = uilabel(app.ClusterPlottingFigure);
            app.DFCFileLabel.Text = 'DFC File ：';
            app.DFC_FileLabel = uilabel(app.ClusterPlottingFigure);
            app.DFC_FileLabel.Text = '';
            % Show the figure after all components are created
            app.ClusterPlottingFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cluster_Plotting_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusterPlottingFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusterPlottingFigure)
        end
    end
end