classdef GetROITC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GETROITC_UIFigure             matlab.ui.Figure
        nullModelLable                matlab.ui.control.Label
        ARR                           matlab.ui.control.CheckBox
        PR                            matlab.ui.control.CheckBox
        tagEditField                  matlab.ui.control.EditField
        tagLabel                      matlab.ui.control.Label
        GMmaskButton                  matlab.ui.control.Button
        e_GMmaskfileEditField         matlab.ui.control.EditField
        GMmaskLabel                   matlab.ui.control.Label
        dataTypeLabel                 matlab.ui.control.Label
        restingData                   matlab.ui.control.CheckBox
        taskData                      matlab.ui.control.CheckBox
        rawData                       matlab.ui.control.CheckBox
        ROITimeCourseExtractionLabel  matlab.ui.control.Label
        chooseSaveDirButton           matlab.ui.control.Button
        e_saveDirEditField            matlab.ui.control.EditField
        saveDirLabel                  matlab.ui.control.Label
        ProgressBarLabel              matlab.ui.control.Label
        ROImaskButton                 matlab.ui.control.Button
        subdirPrefixEditField         matlab.ui.control.EditField
        subdirPrefixLabel             matlab.ui.control.Label
        e_ROImaskfileEditField        matlab.ui.control.EditField
        ROImaskLabel                  matlab.ui.control.Label
        e_inputDirEditField           matlab.ui.control.EditField
        InputDirLabel                 matlab.ui.control.Label
        chooseInputDirButton          matlab.ui.control.Button
        runButton                     matlab.ui.control.Button
        subTipsLabel                  matlab.ui.control.Label
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax % 进度条对象
        isCreateAx % 作为一个flag，判断进度条对象是否创建 % Description
        runned % 作为一个flag，判断是否按过run
        count % task和resting 的 checkbox计数器
        tag % 作为标记ROI TC结果类型，因为ROI mask有很多种，这样可以避免混淆
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp = caller;
            app.e_inputDirEditField.Value = pwd;
            app.isCreateAx = false;
            app.runned = false;
            app.count = 0;

            assignin('base', 'GETROITC_app', app);
        end

        % Close request function: GETROITC_UIFigure
        function GETROITC_UIFigureCloseRequest(app, event)

            app.mainApp.ExtractROITimeCourseButton.Enable = 'on';
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: chooseInputDirButton
        function chooseInputDirButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
            end
            
            app.GETROITC_UIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            
            %创建进度条对象
            app.ax = uiaxes(app.GETROITC_UIFigure, 'Position', [108,22,521,35], 'XTick', [], 'YTick', [],...
                'Color', [1, 1, 1], 'XLim', [0, 1], "YLim", [0 1]);
            app.ax.Visible = 'off';
                            try                     app.ax.Toolbar.Visible = 'off';                 catch                 end
            app.ax.XColor = [0.94, 0.94, 0.94];
            app.ax.YColor = [0.94, 0.94, 0.94];
            app.isCreateAx = true;
            title(app.ax, 'initialization', 'HorizontalAlignment', 'center');
            
            app.ProgressBarLabel.Visible = 'off';
            % core
            [inputDir] = uigetdir('*.*','Please select a save directory');
           if inputDir ~= 0 
                app.e_inputDirEditField.Value = string(inputDir);
                app.subdirPrefixEditField.Value = '';
                app.subTipsLabel.Text = '';
                app.e_saveDirEditField.Value = string([app.e_inputDirEditField.Value filesep 'ROI_mat']);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.GETROITC_UIFigure.Visible = 'on';%防止获取路径之后界面最小化

        end

        % Button pushed function: ROImaskButton
        function ROImaskButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.isCreateAx = false;
                app.runned = false;
            end

            app.GETROITC_UIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';

            if ~app.isCreateAx
                app.ax = uiaxes(app.GETROITC_UIFigure, 'Position', [108,22,521,35], 'XTick', [], 'YTick', [],...
                'Color', [1, 1, 1], 'XLim', [0, 1], "YLim", [0 1]);
                app.ax.Visible = 'off';
                app.ProgressBarLabel.Visible = 'off';
                try 
                    app.ax.Toolbar.Visible = 'off';
                catch
                end
                app.ax.XColor = [0.94, 0.94, 0.94];
                app.ax.YColor = [0.94, 0.94, 0.94];
                app.isCreateAx = true;
            end
            % core
            [filename, pathname] = uigetfile('*.*','Please select a ROI mask file');
            if  filename ~= 0 
                maskAddress = fullfile(pathname, filename);
                app.e_ROImaskfileEditField.Value = string(maskAddress);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.GETROITC_UIFigure.Visible = 'on';%防止获取路径之后界面最小化

            app.subdirPrefixEditField.Value = '';
            app.subTipsLabel.Text = '';
        end

        % Value changed function: subdirPrefixEditField
        function subdirPrefixEditFieldValueChanged(app, event)
            value = app.subdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_inputDirEditField.Value, value);
                subnum = size(sublist,1);
                if subnum ==0
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subjects is ' num2str(subnum)];
                end
                app.subTipsLabel.Text = promptText;
            else
                app.subTipsLabel.Text = "";
            end
        end

        % Button pushed function: chooseSaveDirButton
        function chooseSaveDirButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.isCreateAx = false;
                app.runned = false;
            end

            app.GETROITC_UIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';

            if ~app.isCreateAx
                app.ax = uiaxes(app.GETROITC_UIFigure, 'Position', [108,22,521,35], 'XTick', [], 'YTick', [],...
                'Color', [1, 1, 1], 'XLim', [0, 1], "YLim", [0 1]);
                app.ax.Visible = 'off';
                app.ProgressBarLabel.Visible = 'off';
                try 
                    app.ax.Toolbar.Visible = 'off';
                catch
                end
                app.ax.XColor = [0.94, 0.94, 0.94];
                app.ax.YColor = [0.94, 0.94, 0.94];
                app.isCreateAx = true;
            end

                        % core
            [inputDir] = uigetdir('*.*','Please select a save directory');
           if inputDir ~= 0 
                app.e_saveDirEditField.Value = string(inputDir);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.GETROITC_UIFigure.Visible = 'on';
        end

        % Value changed function: restingData
        function restingDataValueChanged(app, event)
            restingDataValue = app.restingData.Value;
            if (restingDataValue) 
                app.count = app.count + 1;
                app.GMmaskButton.Visible = "on";
                app.e_GMmaskfileEditField.Visible = "on";
                app.GMmaskLabel.Visible = "on";
            else
                app.count = app.count - 1;
                if(app.count == 0)
                    app.GMmaskButton.Visible = "off";
                    app.e_GMmaskfileEditField.Visible = "off";
                    app.GMmaskLabel.Visible = "off";
                end
            end
        end

        % Value changed function: taskData
        function taskDataValueChanged(app, event)
            taskDataValue = app.taskData.Value;
            if (taskDataValue) 
                app.count = app.count + 1;
                app.GMmaskButton.Visible = "on";
                app.e_GMmaskfileEditField.Visible = "on";
                app.GMmaskLabel.Visible = "on";
            else
                app.count = app.count - 1;
                if(app.count == 0)
                    app.GMmaskButton.Visible = "off";
                    app.e_GMmaskfileEditField.Visible = "off";
                    app.GMmaskLabel.Visible = "off";
                end
            end
        end

        % Button pushed function: GMmaskButton
        function GMmaskButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
            end
            
            app.GETROITC_UIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';

            %创建进度条对象
            app.ax = uiaxes(app.GETROITC_UIFigure, 'Position', [108,22,521,35], 'XTick', [], 'YTick', [],...
                'Color', [1, 1, 1], 'XLim', [0, 1], "YLim", [0 1]);
            app.ax.Visible = 'off';
            try
                app.ax.Toolbar.Visible = 'off';
            catch
            end
            app.ax.XColor = [0.94, 0.94, 0.94];
            app.ax.YColor = [0.94, 0.94, 0.94];
            app.isCreateAx = true;
            
            app.ProgressBarLabel.Visible = 'off';
            % core
            [filename, pathname] = uigetfile('*.*','Please select a Grey Matter mask file');
            if  filename ~= 0 
                maskAddress = fullfile(pathname, filename);
                app.e_GMmaskfileEditField.Value = string(maskAddress);
            end


            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.GETROITC_UIFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            if isempty(app.subdirPrefixEditField.Value)

                msg = 'You can type "sub" if the target directory with a prefix "sub" ';
                title = 'Please input a Prefix.';
                msgbox(msg, title, 'warn')
                return
            end

            if isempty(app.e_ROImaskfileEditField.Value)

                msg = 'You should indicate a ROImaskfile before running the GUI';
                title = 'Please choose a ROImaskfile.';
                msgbox(msg, title, 'warn')
                return
            end

            if isempty(app.e_saveDirEditField.Value)
                msg = 'You should indicate a Output Dir before running the GUI';
                title = 'Please choose a Output Dir.';
                msgbox(msg, title, 'warn')
                return
            end

            if ~(app.rawData.Value || app.taskData.Value || app.restingData.Value)
                msg = 'You can choose at least a datatype to generate!';
                title = 'Please choose a datatype.';
                msgbox(msg, title, 'warn')
                return
            end

            if app.count ~= 0 && isempty(app.e_GMmaskfileEditField.Value)
                msg = 'You should indicate a grey matter mask file before running the GUI';
                title = 'Please choose a grey matter file.';
                msgbox(msg, title, 'warn')
                return
            end

            try
                app.runned = true;
                app.ax.Color = [1, 1, 1];
                app.ax.Title.String = 'initializing...';
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ProgressBarLabel.Visible = 'on';
                app.ax.Visible = 'on';
                

%                 app.runButton.Enable = 'off';
%                 app.chooseInputDirButton.Enable = "off";
%                 app.ROImaskButton.Enable = 'off';
%                 app.subdirPrefixEditField.Editable = "off";
%                 app.chooseOutput DirButton.Enable = 'off';
%                 app.rawData.Enable = 'off';
%                 app.taskData.Enable = 'off';
%                 app.restingData.Enable = 'off';
%                 app.GMmaskButton.Enable = 'off';
                ctrlUiobjEdfBtnCkb(app, 'off')
                drawnow
                inputdir = app.e_inputDirEditField.Value;
                prefix = app.subdirPrefixEditField.Value;
                ROIMask = app.e_ROImaskfileEditField.Value;

                if ~exist(app.e_saveDirEditField.Value, "dir")
                    mkdir(app.e_saveDirEditField.Value)
                end
%                 raw
                if app.rawData.Value
                    [ROI_raw_TC, ~] = getROITimeCourse(inputdir, prefix, ROIMask, app);
                    sublist = getSublistByPrefixed(inputdir, prefix);
                    postfix = app.tag;
                    OutputDir = fullfile(app.e_saveDirEditField.Value, postfix);
                    write_3DmatrixROITC_2_2Dmat(ROI_raw_TC, fullfile(OutputDir, 'raw'), sublist, postfix)
                    write_3DmatrixROITC_2_2Dmat(zscore(ROI_raw_TC), fullfile(OutputDir, 'zscore'), sublist, postfix)

                end

                if app.ARR.Value
                    params.nullMdl = 'ar';
                    NullMdlData = NDN_generateNullMldData(ROI_raw_TC, params);

                    OutputDir = fullfile(app.e_saveDirEditField.Value, [char(postfix) '_ARR']);
                    write_3DmatrixROITC_2_2Dmat(NullMdlData, OutputDir, sublist, [char(postfix) '_ARR'])
                    write_3DmatrixROITC_2_2Dmat(zscore(NullMdlData), fullfile(OutputDir, 'zscore'), sublist, [char(postfix) '_ARR'])
                end
                if app.PR.Value
                    params.nullMdl = 'pr';
                    NullMdlData = NDN_generateNullMldData(ROI_raw_TC, params);

                    OutputDir = fullfile(app.e_saveDirEditField.Value, [char(postfix) '_PR']);
                    write_3DmatrixROITC_2_2Dmat(NullMdlData, OutputDir, sublist, [char(postfix) '_PR'])
                    write_3DmatrixROITC_2_2Dmat(zscore(NullMdlData), fullfile(OutputDir, 'zscore'), sublist, [char(postfix) '_PR'])
                end
%                 task&resting
%                 if app.taskData.Value || app.restingData.Value
%                     Maskfile = app.e_GMmaskfileEditField.Value;
%                     sublist = getSublistByPrefixed(inputdir, prefix);
%                     if app.taskData.Value && app.restingData.Value
%                         dataType = 'all';
%                     elseif app.taskData.Value 
%                         dataType = 'task';
%                     else
%                         dataType = 'resting';
%                     end
%                     patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
%                     app.ax.Title.String = 'initializing...';
%                     app.ax.Visible = 'on';
%                     generateTaskResting(inputdir, prefix, dataType, Maskfile, app)
% 
%                     if strcmp(dataType, 'task') || strcmp(dataType, 'all')
%                         postfix = 'task';
%                         patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
%                         inputdir1 = [inputdir filesep '\LOO_ResReg\Task'];
%                         [ROI_task_TC, ~] = getROITimeCourse(inputdir1, prefix, ROIMask, app);
%                         Output Dir = fullfile(app.e_saveDirEditField.Value, 'task');
%                         write_3DmatrixROITC_2_2Dmat(ROI_task_TC, Output Dir, sublist, postfix)
% %                         save(fullfile(app.e_saveDirEditField.Value, "ROI_task_TC.mat"), "ROI_task_TC", "-V7.3")
%                     end
% 
%                     if strcmp(dataType, 'resting') || strcmp(dataType, 'all')
%                         postfix = 'resting';
%                         patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
%                         inputdir2 = [inputdir filesep '\LOO_ResReg\Resting'];
%                         [ROI_resting_TC, ~] = getROITimeCourse(inputdir2, prefix, ROIMask, app);
%                         Output Dir = fullfile(app.e_saveDirEditField.Value, 'resting');
%                         write_3DmatrixROITC_2_2Dmat(ROI_resting_TC, Output Dir, sublist, postfix)                        
%                     end
%                     
%                 end


                msg = 'Congratulations, Time Cource mat file have been saved in Output Dir';
                title = 'Finished';
                msgbox(msg, title)


                ctrlUiobjEdfBtnCkb(app, 'on')

                app.ax.Title.String = 'Congratulations! ROI TC have been saved in "Output Dir"';
            catch ME
%                 app.chooseInputDirButton.Enable = "on";
%                 app.runButton.Enable = 'on';
%                 app.ROImaskButton.Enable = 'on';
%                 app.subdirPrefixEditField.Editable = "on";
%                 app.chooseOutput DirButton.Enable = 'on';
%                 app.rawData.Enable = 'on';
%                 app.taskData.Enable = 'on';
%                 app.restingData.Enable = 'on';
%                 app.GMmaskButton.Enable = 'on';
                ctrlUiobjEdfBtnCkb(app, 'on')

                if ME.message 
                    msg = ME.message;
                else
                    msg = 'There is an error in loading ROI Time Course';
                end
                
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error!';
            end

        end

        % Value changed function: tagEditField
        function tagEditFieldValueChanged(app, event)
            app.tag = app.tagEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GETROITC_UIFigure = uifigure('Visible', 'off');
            colormap(app.GETROITC_UIFigure, 'summer');
            app.GETROITC_UIFigure.Name = 'GetROITC';
            app.GETROITC_UIFigure.CloseRequestFcn = createCallbackFcn(app, @GETROITC_UIFigureCloseRequest, true);
            app.subTipsLabel = uilabel(app.GETROITC_UIFigure);
            app.subTipsLabel.Text = '';
            app.runButton = uibutton(app.GETROITC_UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'Run';
            app.chooseInputDirButton = uibutton(app.GETROITC_UIFigure, 'push');
            app.chooseInputDirButton.ButtonPushedFcn = createCallbackFcn(app, @chooseInputDirButtonPushed, true);
            app.chooseInputDirButton.Text = 'choose';
            app.InputDirLabel = uilabel(app.GETROITC_UIFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_inputDirEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.e_inputDirEditField.Editable = 'off';
            app.ROImaskLabel = uilabel(app.GETROITC_UIFigure);
            app.ROImaskLabel.Text = 'ROImask file:';
            app.e_ROImaskfileEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.e_ROImaskfileEditField.Editable = 'off';
            app.subdirPrefixLabel = uilabel(app.GETROITC_UIFigure);
            app.subdirPrefixLabel.Text = 'Subdir Prefix:';
            app.subdirPrefixEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.subdirPrefixEditField.ValueChangedFcn = createCallbackFcn(app, @subdirPrefixEditFieldValueChanged, true);
            app.ROImaskButton = uibutton(app.GETROITC_UIFigure, 'push');
            app.ROImaskButton.ButtonPushedFcn = createCallbackFcn(app, @ROImaskButtonPushed, true);
            app.ROImaskButton.Text = 'choose';
            app.ProgressBarLabel = uilabel(app.GETROITC_UIFigure);
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = 'Progress Bar:';
            app.saveDirLabel = uilabel(app.GETROITC_UIFigure);
            app.saveDirLabel.Text = 'Output Dir:';
            app.e_saveDirEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.e_saveDirEditField.Editable = 'off';
            app.chooseSaveDirButton = uibutton(app.GETROITC_UIFigure, 'push');
            app.chooseSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @chooseSaveDirButtonPushed, true);
            app.chooseSaveDirButton.Text = 'choose';
            app.ROITimeCourseExtractionLabel = uilabel(app.GETROITC_UIFigure);
            app.ROITimeCourseExtractionLabel.Text = 'ROI Time Course Extraction';
            app.rawData = uicheckbox(app.GETROITC_UIFigure);
            app.rawData.Visible = 'off';
            app.rawData.Text = 'Raw Data';
            app.rawData.Value = true;
            app.taskData = uicheckbox(app.GETROITC_UIFigure);
            app.taskData.ValueChangedFcn = createCallbackFcn(app, @taskDataValueChanged, true);
            app.taskData.Visible = 'off';
            app.taskData.Text = 'Task Data';
            app.restingData = uicheckbox(app.GETROITC_UIFigure);
            app.restingData.ValueChangedFcn = createCallbackFcn(app, @restingDataValueChanged, true);
            app.restingData.Visible = 'off';
            app.restingData.Text = 'Resting Data';
            app.dataTypeLabel = uilabel(app.GETROITC_UIFigure);
            app.dataTypeLabel.Visible = 'off';
            app.dataTypeLabel.Text = 'dataType:';
            app.GMmaskLabel = uilabel(app.GETROITC_UIFigure);
            app.GMmaskLabel.Visible = 'off';
            app.GMmaskLabel.Text = 'GMmask file:';
            app.e_GMmaskfileEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.e_GMmaskfileEditField.Editable = 'off';
            app.e_GMmaskfileEditField.Visible = 'off';
            app.GMmaskButton = uibutton(app.GETROITC_UIFigure, 'push');
            app.GMmaskButton.ButtonPushedFcn = createCallbackFcn(app, @GMmaskButtonPushed, true);
            app.GMmaskButton.Visible = 'off';
            app.GMmaskButton.Text = 'choose';
            app.tagLabel = uilabel(app.GETROITC_UIFigure);
            app.tagLabel.Text = 'Tag:';
            app.tagEditField = uieditfield(app.GETROITC_UIFigure, 'text');
            app.tagEditField.ValueChangedFcn = createCallbackFcn(app, @tagEditFieldValueChanged, true);
            app.PR = uicheckbox(app.GETROITC_UIFigure);
            app.PR.Text = 'PR (Phase Randomization)';
            app.ARR = uicheckbox(app.GETROITC_UIFigure);
            app.ARR.Text = 'ARR (Autoregressive Randomization)';
            app.nullModelLable = uilabel(app.GETROITC_UIFigure);
            app.nullModelLable.Text = 'Null Model (Option):';
            % Show the figure after all components are created
            app.GETROITC_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetROITC_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GETROITC_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GETROITC_UIFigure)
        end
    end
end