classdef ISCAP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISCAPFigure                 matlab.ui.Figure
        n_PccEditField              matlab.ui.control.EditField
        PccLabel                    matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        BestK_Label                 matlab.ui.control.Label
        BestKBtn                    matlab.ui.control.Button
        n_NEditField                matlab.ui.control.EditField
        NEditFieldLabel             matlab.ui.control.Label
        n_KmaxEditField             matlab.ui.control.EditField
        KmaxEditFieldLabel          matlab.ui.control.Label
        BestKOptionalLabel          matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        PnEditField                 matlab.ui.control.EditField
        pNLabel                     matlab.ui.control.Label
        n_TREditField               matlab.ui.control.EditField
        TRLabel                     matlab.ui.control.Label
        KEditField                  matlab.ui.control.EditField
        KLabel                      matlab.ui.control.Label
        PpEditField                 matlab.ui.control.EditField
        pPLabel                     matlab.ui.control.Label
        TmotEditField               matlab.ui.control.EditField
        TmotLabel                   matlab.ui.control.Label
        RunNameEditField            matlab.ui.control.EditField
        runNameLabel                matlab.ui.control.Label
        ParametersLabel             matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        e_SavedDirEditField         matlab.ui.control.EditField
        OutputDirLabel              matlab.ui.control.Label
        SubdirPrefixEditField       matlab.ui.control.EditField
        SubdirPrefixEditFieldLabel  matlab.ui.control.Label
        e_GMmaskfileEditField       matlab.ui.control.EditField
        GMMaskfileLabel             matlab.ui.control.Label
        e_InputDirEditField         matlab.ui.control.EditField
        InputDirLabel               matlab.ui.control.Label
        SavedDirBtn                 matlab.ui.control.Button
        titleLabel                  matlab.ui.control.Label
        ProgressBarLabel            matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        GMBtn                       matlab.ui.control.Button
        InputDirBtn                 matlab.ui.control.Button
        RunBtn                      matlab.ui.control.Button
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax %进度条对象
        isCreateAx %作为一个flag，判断进度条对象是否创建
        runned %作为一个flag，判断是否按过run
        title
        method
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, title, method)
            % 初始化editorField
            app.e_InputDirEditField.Value = pwd;
            [~, folderName, ~] = fileparts(app.e_InputDirEditField.Value);
            app.RunNameEditField.Value = folderName;
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [method '-Result']);
            % 初始化界面标识
            app.ISCAPFigure.Name = method;
            app.titleLabel.Text = title;
            % 初始化app属性
            app.title = title;
            app.method = method;
            app.mainApp=caller;
            app.isCreateAx = false;
            app.runned = false;

            assignin('base', [method '_app'], app);
            %             app.UI
        end

        % Close request function: ISCAPFigure
        function ISCAPFigureCloseRequest(app, event)
            app.mainApp.GMMethodButton.Enable = 'on';
            app.mainApp.GMVoxelMethodsDropDown.Enable = "on";
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            % 创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end


            [inputDir] = uigetdir('*.*','Please select an input directory');
            if inputDir ~= 0
                % saveDir
                app.e_InputDirEditField.Value = string(inputDir);
                app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, [app.method '-Result']);
                app.SubdirPrefixEditField.Value = '';
                app.Label.Text = '';
                app.BestK_Label.Text = '';
                % runName
                [~, folderName, ~] = fileparts(inputDir);
                app.RunNameEditField.Value = folderName;

                app.Switch.Value = 'off';
                app.SwitchValueChanged(app)
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: SavedDirBtn
        function SavedDirBtnButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [inputDir] = uigetdir('*.*','Please select a saved directory');
            if inputDir ~= 0
                app.e_SavedDirEditField.Value = string(inputDir);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: GMBtn
        function GMBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.ISCAPFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [filename, pathname] = uigetfile('*.*','Please select a Grey Matter mask file');
            if filename ~= 0
                maskAddress = fullfile(pathname, filename);
                app.e_GMmaskfileEditField.Value = string(maskAddress);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.ISCAPFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Value changed function: SubdirPrefixEditField
        function SubdirPrefixEditFieldValueChanged(app, event)
            value = app.SubdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_InputDirEditField.Value, value);
                subnum = size(sublist,1);
                if subnum == 0
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subjects is ' num2str(subnum)];
                end
                app.Label.Text = promptText;
            else
                app.Label.Text = "";
            end

        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            if isUiobjNull(app)
                return
            end

            try
                if ~app.isCreateAx
                    initializeProgress(app)
                end
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.runned = true;
                app.ProgressBarLabel.Visible = 'on';
                app.ax.Visible = 'on';
                drawnow
                ctrlUiobjEdfBtnCkb(app, 'off')

                inputDir = app.e_InputDirEditField.Value;
                prefix = app.SubdirPrefixEditField.Value;
                grayMatterMask = app.e_GMmaskfileEditField.Value;
                savedDir = app.e_SavedDirEditField.Value;

                params.runName = app.RunNameEditField.Value;
                params.Tmot = str2double(app.TmotEditField.Value);
                params.K = str2double(app.KEditField.Value);
                params.Pp = str2double(app.PpEditField.Value);
                params.Pn = str2double(app.PnEditField.Value);
                params.method = app.method;
                params.isBestK = false;

                if ~isempty(app.n_TREditField.Value)
                    params.TR = str2double(app.n_TREditField.Value);
                end
                NDN_ISCAP(inputDir, prefix, grayMatterMask, savedDir, params, app)
                msg = ['Congratulations,' app.method 'is finished'];
                title = 'Finished';
                msgbox(msg, title)
                ctrlUiobjEdfBtnCkb(app, 'on')
                app.ax.Title.String = ['Congratulations! ' app.method ' Result have been saved in "Output Dir"'];
            catch ME
                ctrlUiobjEdfBtnCkb(app, 'on')


                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing ' app.method ];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end


        end

        % Button pushed function: BestKBtn
        function BestKBtnButtonPushed(app, event)
            if isUiobjNull(app)
                return
            end

            app.BestK_Label.Text = "";
            if isempty(app.n_KmaxEditField.Value)
                msg = ['The value of Kmax should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for Kmax' ];
                msgbox(msg, title, 'warn')
                return
            end
            if isempty(app.n_NEditField.Value)
                msg = ['The value of N should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for N'];
                msgbox(msg, title, 'warn')
                return
            end
            if isempty(app.n_PccEditField.Value)
                msg = ['The value of Pcc should not be empty. Please assign a new value.'];
                title =  ['Please assign a value for Pcc'];
                msgbox(msg, title, 'warn')
                return
            end
            try
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.runned = true;
                app.ProgressBarLabel.Visible = 'on';
                app.ax.Visible = 'on';
                drawnow
                ctrlUiobjEdfBtnCkb(app, 'off')

                inputDir = app.e_InputDirEditField.Value;
                prefix = app.SubdirPrefixEditField.Value;
                grayMatterMask = app.e_GMmaskfileEditField.Value;
                savedDir = app.e_SavedDirEditField.Value;

                params.runName = app.RunNameEditField.Value;
                params.Tmot = str2double(app.TmotEditField.Value);
                params.K = str2double(app.KEditField.Value);
                params.Pp = str2double(app.PpEditField.Value);
                params.Pn = str2double(app.PnEditField.Value);
                params.Pcc = str2double(app.n_PccEditField.Value);
                params.N = str2double(app.n_NEditField.Value);
                params.Kmax = str2double(app.n_KmaxEditField.Value);
                params.isBestK = false;

                params.method = app.method;

                if ~isempty(app.n_TREditField.Value)
                    params.TR = str2double(app.n_TREditField.Value);
                end

                NDN_ISCAP(inputDir, prefix, grayMatterMask, savedDir, params, app)
                ctrlUiobjEdfBtnCkb(app, 'on')
            catch ME
                ctrlUiobjEdfBtnCkb(app, 'on')


                if ME.message
                    msg = ME.message;
                else
                    msg = ['There is an error in computing  best K' app.method ];
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            state = app.Switch.Value;
            app.n_KmaxEditField.Visible = state;
            app.n_NEditField.Visible = state;
            app.n_PccEditField.Visible = state;
            app.BestKBtn.Visible = state;
            app.KmaxEditFieldLabel.Visible = state;
            app.NEditFieldLabel.Visible = state;
            app.PccLabel.Visible = state;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISCAPFigure = uifigure('Visible', 'off');
            app.ISCAPFigure.Name = 'ISCAP';
            app.ISCAPFigure.CloseRequestFcn = createCallbackFcn(app, @ISCAPFigureCloseRequest, true);
            app.RunBtn = uibutton(app.ISCAPFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.ISCAPFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.GMBtn = uibutton(app.ISCAPFigure, 'push');
            app.GMBtn.ButtonPushedFcn = createCallbackFcn(app, @GMBtnPushed, true);
            app.GMBtn.Text = 'choose';
            app.Label = uilabel(app.ISCAPFigure);
            app.Label.Text = '';
            app.ProgressBarLabel = uilabel(app.ISCAPFigure);
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = 'Progress Bar:';
            app.titleLabel = uilabel(app.ISCAPFigure);
            app.titleLabel.Text = 'Inter-Subject Coactivation Pattern (ISCAP)';
            app.SavedDirBtn = uibutton(app.ISCAPFigure, 'push');
            app.SavedDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SavedDirBtnButtonPushed, true);
            app.SavedDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.ISCAPFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_InputDirEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_InputDirEditField.Editable = 'off';
            app.GMMaskfileLabel = uilabel(app.ISCAPFigure);
            app.GMMaskfileLabel.Text = 'GM Mask file:';
            app.e_GMmaskfileEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_GMmaskfileEditField.Editable = 'off';
            app.SubdirPrefixEditFieldLabel = uilabel(app.ISCAPFigure);
            app.SubdirPrefixEditFieldLabel.Text = 'Subdir Prefix:';
            app.SubdirPrefixEditField = uieditfield(app.ISCAPFigure, 'text');
            app.SubdirPrefixEditField.ValueChangedFcn = createCallbackFcn(app, @SubdirPrefixEditFieldValueChanged, true);
            app.OutputDirLabel = uilabel(app.ISCAPFigure);
            app.OutputDirLabel.Text = 'Output Dir:';
            app.e_SavedDirEditField = uieditfield(app.ISCAPFigure, 'text');
            app.e_SavedDirEditField.Editable = 'off';
            app.Label_2 = uilabel(app.ISCAPFigure);
            app.Label_2.Text = '';
            app.ParametersLabel = uilabel(app.ISCAPFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.runNameLabel = uilabel(app.ISCAPFigure);
            app.runNameLabel.Text = 'RunName:';
            app.RunNameEditField = uieditfield(app.ISCAPFigure, 'text');
            app.TmotLabel = uilabel(app.ISCAPFigure);
            app.TmotLabel.Text = 'Tmot:';
            app.TmotEditField = uieditfield(app.ISCAPFigure, 'text');
            app.TmotEditField.Value = '0.5';
            app.pPLabel = uilabel(app.ISCAPFigure);
            app.pPLabel.Text = 'Pp';
            app.PpEditField = uieditfield(app.ISCAPFigure, 'text');
            app.PpEditField.Value = '100';
            app.KLabel = uilabel(app.ISCAPFigure);
            app.KLabel.Text = 'K:';
            app.KEditField = uieditfield(app.ISCAPFigure, 'text');
            app.KEditField.Value = '4';
            app.TRLabel = uilabel(app.ISCAPFigure);
            app.TRLabel.Text = 'TR:';
            app.n_TREditField = uieditfield(app.ISCAPFigure, 'text');
            app.pNLabel = uilabel(app.ISCAPFigure);
            app.pNLabel.Text = 'Pn';
            app.PnEditField = uieditfield(app.ISCAPFigure, 'text');
            app.PnEditField.Value = '100';
            app.Label_3 = uilabel(app.ISCAPFigure);
            app.Label_3.Text = '';
            app.BestKOptionalLabel = uilabel(app.ISCAPFigure);
            app.BestKOptionalLabel.Text = 'Best K (Optional):';
            app.KmaxEditFieldLabel = uilabel(app.ISCAPFigure);
            app.KmaxEditFieldLabel.Visible = 'off';
            app.KmaxEditFieldLabel.Text = 'Kmax:';
            app.n_KmaxEditField = uieditfield(app.ISCAPFigure, 'text');
            app.n_KmaxEditField.Visible = 'off';
            app.NEditFieldLabel = uilabel(app.ISCAPFigure);
            app.NEditFieldLabel.Visible = 'off';
            app.NEditFieldLabel.Text = 'N:';
            app.n_NEditField = uieditfield(app.ISCAPFigure, 'text');
            app.n_NEditField.Visible = 'off';
            app.BestKBtn = uibutton(app.ISCAPFigure, 'push');
            app.BestKBtn.ButtonPushedFcn = createCallbackFcn(app, @BestKBtnButtonPushed, true);
            app.BestKBtn.Visible = 'off';
            app.BestKBtn.Text = 'Best K';
            app.BestK_Label = uilabel(app.ISCAPFigure);
            app.BestK_Label.Text = '';
            app.Switch = uiswitch(app.ISCAPFigure, 'slider');
            app.Switch.Items = {'OFF', 'ON'};
            app.Switch.ItemsData = {'off', 'on'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'off';
            app.PccLabel = uilabel(app.ISCAPFigure);
            app.PccLabel.Visible = 'off';
            app.PccLabel.Text = 'Pcc:';
            app.n_PccEditField = uieditfield(app.ISCAPFigure, 'text');
            app.n_PccEditField.Visible = 'off';
            % Show the figure after all components are created
            app.ISCAPFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISCAP_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ISCAPFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISCAPFigure)
        end
    end
end