classdef SFCROI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SFCUIFigure        matlab.ui.Figure
        StaticFunctionalConnectivitySFCLabel  matlab.ui.control.Label
        ProgressBarLabel   matlab.ui.control.Label
        SaveDirBtn         matlab.ui.control.Button
        SavedDirEditField  matlab.ui.control.EditField
        PrefixLabel        matlab.ui.control.Label
        InputDirEditField  matlab.ui.control.EditField
        InputDirLabel      matlab.ui.control.Label
        InputDirBtn        matlab.ui.control.Button
        RunBtn             matlab.ui.control.Button
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax %进度条对象
        isCreateAx %作为一个flag，判断进度条对象是否创建 % Description
        runned %作为一个flag，判断是否按过run
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp = caller;
            app.InputDirEditField.Value = pwd;
            app.SavedDirEditField.Value = fullfile(app.InputDirEditField.Value, 'SFC-Result');
            app.isCreateAx = false;
            app.runned = false;

            assignin('base', 'SFC_app', app);

        end

        % Close request function: SFCUIFigure
        function SFCUIFigureCloseRequest(app, event)
            app.mainApp.ROIMethodsDropDown.Enable = "on";
            app.mainApp.ROIMethodButton.Enable = "on";
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = 0;
            end
            
            app.SFCUIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end
            
            app.ProgressBarLabel.Visible = 'off';

            [inputDir] = uigetdir('*.*','Please select a inupt directory');
           if inputDir ~= 0 
                app.InputDirEditField.Value = string(inputDir);
            end
            app.SavedDirEditField.Value = fullfile(app.InputDirEditField.Value, 'SFC-Result');
            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.SFCUIFigure.Visible = 'on';%防止获取路径之后界面最小化

        end

        % Button pushed function: SaveDirBtn
        function SaveDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = 0;
            end
            app.SFCUIFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            if ~app.isCreateAx
                initializeProgress(app)
            end
            [savedDir] = uigetdir('*.*','Please select a saved directory');
            if  savedDir ~= 0 
                app.SavedDirEditField.Value = string(savedDir);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.SFCUIFigure.Visible = 'on';%防止获取路径之后界面最小化

        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)                
            end
            
            if isempty(app.InputDirEditField.Value)
                msg = 'You must choose an input dir to start';
                title = 'Please input a Input Dir';
                msgbox(msg, title, 'warn')

            else
                try
                    patch(app.ax, [0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                    app.ax.Title.String = 'initializing...';
                    app.ax.Visible = 'on';
                    app.ProgressBarLabel.Visible = 'on';
                    app.runned = true;


                    app.RunBtn.Enable = 'off';
                    app.InputDirBtn.Enable = "off";
                    app.SaveDirBtn.Enable = 'off';
                    
                    drawnow

                    computeSFCByMat(app.InputDirEditField.Value, app.SavedDirEditField.Value, app);
                    
                    msg = 'Congratulations,SFC is finished';
                    title = 'Finished';
                    msgbox(msg, title)
                    app.RunBtn.Enable = 'on';
                    app.InputDirBtn.Enable = "on";
                    app.SaveDirBtn.Enable = 'on';
                    app.ax.Title.String = 'Congratulations! SFC Result have been saved in "Output Dir"';
                catch ME
                    app.InputDirBtn.Enable = "on";
                    app.RunBtn.Enable = 'on';
                    app.SaveDirBtn.Enable = 'on';
                    if ME.message 
                        msg = ME.message;
                    else
                        msg = 'There is an error in computing SFC';
                    end
                    title = 'Error';
                    msgbox(msg, title, 'error');
                    app.ax.Title.String = 'Error';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SFCUIFigure = uifigure('Visible', 'off');
            app.SFCUIFigure.Name = 'SFC';
            app.SFCUIFigure.CloseRequestFcn = createCallbackFcn(app, @SFCUIFigureCloseRequest, true);
            app.RunBtn = uibutton(app.SFCUIFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.SFCUIFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.SFCUIFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.InputDirEditField = uieditfield(app.SFCUIFigure, 'text');
            app.InputDirEditField.Editable = 'off';
            app.PrefixLabel = uilabel(app.SFCUIFigure);
            app.PrefixLabel.Text = 'Output Dir:';
            app.SavedDirEditField = uieditfield(app.SFCUIFigure, 'text');
            app.SavedDirEditField.Editable = 'off';
            app.SaveDirBtn = uibutton(app.SFCUIFigure, 'push');
            app.SaveDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveDirBtnPushed, true);
            app.SaveDirBtn.Text = 'choose';
            app.ProgressBarLabel = uilabel(app.SFCUIFigure);
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = 'Progress Bar:';
            app.StaticFunctionalConnectivitySFCLabel = uilabel(app.SFCUIFigure);
            app.StaticFunctionalConnectivitySFCLabel.Text = 'Static Functional Connectivity (SFC)';
            % Show the figure after all components are created
            app.SFCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFCROI_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SFCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SFCUIFigure)
        end
    end
end