classdef SWISC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SWISCFigure                 matlab.ui.Figure
        WindowsizeEditField         matlab.ui.control.EditField
        WindowsizeEditFieldLabel    matlab.ui.control.Label
        ParametersLabel             matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        SavedDirBtn                 matlab.ui.control.Button
        e_SavedDirEditField         matlab.ui.control.EditField
        OutputDirLabel              matlab.ui.control.Label
        SlidingWindowInterSubjectCorrelationSWISCLabel  matlab.ui.control.Label
        ProgressBarLabel            matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        GMBtn                       matlab.ui.control.Button
        SubdirPrefixEditField       matlab.ui.control.EditField
        SubdirPrefixEditFieldLabel  matlab.ui.control.Label
        e_GMmaskfileEditField       matlab.ui.control.EditField
        PrefixLabel                 matlab.ui.control.Label
        e_InputDirEditField         matlab.ui.control.EditField
        InputDirLabel               matlab.ui.control.Label
        InputDirBtn                 matlab.ui.control.Button
        RunBtn                      matlab.ui.control.Button
    end


    properties (Access = public)
        mainApp % 主界面对象
        ax %进度条对象
        isCreateAx %作为一个flag，判断进度条对象是否创建
        runned %作为一个flag，判断是否按过run
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mainApp=caller;
            app.e_InputDirEditField.Value = pwd;
            app.isCreateAx = false;
            app.runned = false;
            app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, 'SWISC-Result');
            assignin('base', 'SWISC_app', app);
            %             app.UI
        end

        % Close request function: SWISCFigure
        function SWISCFigureCloseRequest(app, event)
            app.mainApp.GMMethodButton.Enable = 'on';
            app.mainApp.GMVoxelMethodsDropDown.Enable = "on";
            
            evalin("base",'clear')
            delete(app)
        end

        % Button pushed function: InputDirBtn
        function InputDirBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.SWISCFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end


            [inputDir] = uigetdir('*.*','Please select an input directory');
            if inputDir ~= 0
                app.e_InputDirEditField.Value = string(inputDir);
                app.e_SavedDirEditField.Value = fullfile(app.e_InputDirEditField.Value, 'ISC-Result');
                app.SubdirPrefixEditField.Value = '';
                app.Label.Text = '';
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.SWISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: SavedDirBtn
        function SavedDirBtnButtonPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.SWISCFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [inputDir] = uigetdir('*.*','Please select a saved directory');
            if inputDir ~= 0
                app.e_SavedDirEditField.Value = string(inputDir);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.SWISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: GMBtn
        function GMBtnPushed(app, event)
            if app.runned
                delete(app.ax)
                app.runned = false;
                app.isCreateAx = false;
            end
            app.SWISCFigure.Visible = 'off';
            app.mainApp.NaDyNetUIFigure.Visible = 'off';
            %创建进度条对象
            if ~app.isCreateAx
                initializeProgress(app)
            end

            [filename, pathname] = uigetfile('*.*','Please select a Grey Matter mask file');
            if filename ~= 0
                maskAddress = fullfile(pathname, filename);
                app.e_GMmaskfileEditField.Value = string(maskAddress);
            end

            app.mainApp.NaDyNetUIFigure.Visible = 'on';
            app.SWISCFigure.Visible = 'on';%防止获取路径之后界面最小化
        end

        % Button pushed function: RunBtn
        function RunBtnPushed(app, event)
            if isUiobjNull(app)
                return 
            end

            try
                patch(app.ax,[0, 1, 1, 0], [0, 0, 1, 1], [1, 1, 1]);
                app.ax.Title.String = 'initializing...';
                app.ax.Visible = 'on';
                app.ProgressBarLabel.Visible = 'on';
                app.runned = true;
                
                ctrlUiobjEdfBtnCkb(app, 'off')
                drawnow

                inputdir = app.e_InputDirEditField.Value;
                prefix = app.SubdirPrefixEditField.Value;
                grayMatterMask = app.e_GMmaskfileEditField.Value;
                savedDir = app.e_SavedDirEditField.Value;

                params.sigma = 3;
                params.wsize = app.WindowsizeEditField.Value;

                NDN_SWISC(inputdir, prefix, grayMatterMask, params, savedDir, app)
                msg = 'Congratulations, SWISC is finished';
                title = 'Finished';
                msgbox(msg, title)
                ctrlUiobjEdfBtnCkb(app, 'on')

                app.ax.Title.String = 'Congratulations! SWISC Result have been saved in "Output Dir"';
            catch ME
                app.RunBtn.Enable = "on";
                app.InputDirBtn.Enable = "on";
                app.GMBtn.Enable = 'on';
                app.SavedDirBtn.Enable = "on";
                app.SubdirPrefixEditField.Editable = "on";

                if ME.message
                    msg = ME.message;
                else
                    msg = 'There is an error in computing SWISC';
                end
                title = 'Error';
                msgbox(msg, title, 'error');
                app.ax.Title.String = 'Error';
            end


        end

        % Value changed function: SubdirPrefixEditField
        function SubdirPrefixEditFieldValueChanged(app, event)
            value = app.SubdirPrefixEditField.Value;
            if ~isempty(value)
                sublist = getSublistByPrefixed(app.e_InputDirEditField.Value, value);
                subnum = size(sublist,1);
                if subnum ==0
                    promptText = 'Error Input!';
                else
                    promptText = ['The number of target subjects is ' num2str(subnum)];
                end
                app.Label.Text = promptText;
            else
                app.Label.Text = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SWISCFigure = uifigure('Visible', 'off');
            app.SWISCFigure.Name = 'SWISC';
            app.SWISCFigure.CloseRequestFcn = createCallbackFcn(app, @SWISCFigureCloseRequest, true);
            app.RunBtn = uibutton(app.SWISCFigure, 'push');
            app.RunBtn.ButtonPushedFcn = createCallbackFcn(app, @RunBtnPushed, true);
            app.RunBtn.Text = 'Run';
            app.InputDirBtn = uibutton(app.SWISCFigure, 'push');
            app.InputDirBtn.ButtonPushedFcn = createCallbackFcn(app, @InputDirBtnPushed, true);
            app.InputDirBtn.Text = 'choose';
            app.InputDirLabel = uilabel(app.SWISCFigure);
            app.InputDirLabel.Text = 'Input Dir:';
            app.e_InputDirEditField = uieditfield(app.SWISCFigure, 'text');
            app.e_InputDirEditField.Editable = 'off';
            app.PrefixLabel = uilabel(app.SWISCFigure);
            app.PrefixLabel.Text = 'GMmask file:';
            app.e_GMmaskfileEditField = uieditfield(app.SWISCFigure, 'text');
            app.e_GMmaskfileEditField.Editable = 'off';
            app.SubdirPrefixEditFieldLabel = uilabel(app.SWISCFigure);
            app.SubdirPrefixEditFieldLabel.Text = 'Subdir Prefix:';
            app.SubdirPrefixEditField = uieditfield(app.SWISCFigure, 'text');
            app.SubdirPrefixEditField.ValueChangedFcn = createCallbackFcn(app, @SubdirPrefixEditFieldValueChanged, true);
            app.GMBtn = uibutton(app.SWISCFigure, 'push');
            app.GMBtn.ButtonPushedFcn = createCallbackFcn(app, @GMBtnPushed, true);
            app.GMBtn.Text = 'choose';
            app.Label = uilabel(app.SWISCFigure);
            app.Label.Text = '';
            app.ProgressBarLabel = uilabel(app.SWISCFigure);
            app.ProgressBarLabel.Visible = 'off';
            app.ProgressBarLabel.Text = 'Progress Bar:';
            app.SlidingWindowInterSubjectCorrelationSWISCLabel = uilabel(app.SWISCFigure);
            app.SlidingWindowInterSubjectCorrelationSWISCLabel.Text = 'Sliding-Window Inter-Subject Correlation (SWISC)';
            app.OutputDirLabel = uilabel(app.SWISCFigure);
            app.OutputDirLabel.Text = 'Output Dir:';
            app.e_SavedDirEditField = uieditfield(app.SWISCFigure, 'text');
            app.e_SavedDirEditField.Editable = 'off';
            app.SavedDirBtn = uibutton(app.SWISCFigure, 'push');
            app.SavedDirBtn.ButtonPushedFcn = createCallbackFcn(app, @SavedDirBtnButtonPushed, true);
            app.SavedDirBtn.Text = 'choose';
            app.Label_2 = uilabel(app.SWISCFigure);
            app.Label_2.Text = '';
            app.ParametersLabel = uilabel(app.SWISCFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.WindowsizeEditFieldLabel = uilabel(app.SWISCFigure);
            app.WindowsizeEditFieldLabel.Text = 'Window size:';
            app.WindowsizeEditField = uieditfield(app.SWISCFigure, 'text');
            app.WindowsizeEditField.Value = '30';
            % Show the figure after all components are created
            app.SWISCFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SWISC_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SWISCFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SWISCFigure)
        end
    end
end