classdef NDN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NaDyNetUIFigure               matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        ExtractROITimeCourseButton    matlab.ui.control.Button
        STEP1ROITimeCourseExtractionLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        STEP2MethodSelectionLabel     matlab.ui.control.Label
        ROIMethodsDropDownLabel       matlab.ui.control.Label
        ROIMethodsDropDown            matlab.ui.control.DropDown
        GMVoxelMethodsDropDownLabel   matlab.ui.control.Label
        GMVoxelMethodsDropDown        matlab.ui.control.DropDown
        ROIMethodButton               matlab.ui.control.Button
        GMMethodButton                matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        ClusteringPlottingButton      matlab.ui.control.Button
        STEP3ClusteringPlottingLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ISCapp % Description
        SWISCapp
        ISCAPapp
        CAPapp
        SFCapp
        GetROITCapp
        SWFCapp
        ISSWFCapp
        DCCapp
        ISDCCapp
        FLSapp
        ISFLSapp
        ISGLKFapp
        GLKFapp
        ISMTDapp
        MTDapp
        Cluster_Plotapp
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            useViewInitialization(app)
            disp('')
            disp('Welcome to using NaDyNet - Naturalistic Dynamic Network Toolbox')
            [path] = fileparts(which("NaDyNet"));
            pathstr = fileparts(path);
            NDNImg = [pathstr '\assets\NDN.png'];
            %             app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            
            
            [img] = imread(NDNImg);
            
            %             Image = imshow(img, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'image');
            % Adjust axes properties to remove extra space
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
            %             app.UIAxes.Toolbar.Visible = 'off'; % Optional: hide the axes toolbar
            %             app.UIAxes.Interactions = []; % Disable default interactions
            
            % Turn off axis limits auto adjustment
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLimMode = 'manual';


            % Display image using imagesc to fill the entire axes
            imagesc(app.UIAxes, img);
            
            % Set axis properties to make sure image fills the axes
            axis(app.UIAxes, 'off'); % Turn off axis lines and labels
            axis(app.UIAxes, 'image'); % Set aspect ratio so data units are equal
            app.UIAxes.Position = [1,617,756,141];
            % Optional: Adjust tight inset to remove extra space if needed
            set(app.UIAxes,'LooseInset', max(get(app.UIAxes,'TightInset'), 0.02));
            useViewInitialization(app)
        end

        % Close request function: NaDyNetUIFigure
        function NaDyNetUIFigureCloseRequest(app, event)
            delete(app.ISCapp)
            delete(app.SWISCapp)
            delete(app.ISCAPapp)
            delete(app.CAPapp)
            delete(app.SFCapp)
            delete(app.GetROITCapp)
            delete(app.SWFCapp)
            delete(app.ISSWFCapp)
            delete(app.DCCapp)
            delete(app.ISDCCapp)
            delete(app.FLSapp)
            delete(app.ISFLSapp)
            delete(app.ISGLKFapp)
            delete(app.GLKFapp)
            delete(app.ISMTDapp)
            delete(app.MTDapp)
            delete(app.Cluster_Plotapp)
            delete(app)
            disp('')
            disp('Thank you for using NaDyNet - Naturalistic Dynamic Network Toolbox')
        end

        % Button pushed function: ExtractROITimeCourseButton
        function ExtractROITimeCourseButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ExtractROITimeCourseButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.GetROITCapp = GetROITC_GUI(app);
        end

        % Value changed function: GMVoxelMethodsDropDown
        function GMVoxelMethodsDropDownValueChanged(app, event)
            %             value = app.GMVoxelMethodsDropDown.Value;
            %             if ~isequal(value, ' ')
            %                 app.GMVoxelMethodsDropDown.Enable = "off";
            %             end
            %             if isequal(value, 'ISCAP')
            %                 app.ISCAPapp = ISCAP_GUI(app);
            %             end
            %             if isequal(value, 'ISC')
            %                 app.ISCapp = ISC_GUI(app);
            %             end
        end

        % Value changed function: ROIMethodsDropDown
        function ROIMethodsDropDownValueChanged(app, event)
            %             value = app.ROIMethodsDropDown.Value;
            %             if ~isequal(value, ' ')
            %                 app.ROIMethodsDropDown.Enable = "off";
            %             end
            %             if isequal(value, 'SFC')
            %                 app.SFCapp = SFCROI_GUI(app);
            %             end
        end

        % Button pushed function: GMMethodButton
        function GMMethodButtonPushed(app, event)
            value = app.GMVoxelMethodsDropDown.Value;
            if ~isequal(value, ' ')
                app.GMVoxelMethodsDropDown.Enable = "off";
                app.GMMethodButton.Enable = "off";
            else
                return
            end
            
            
            if isequal(value, 'ISCAP')
                app.ISCAPapp = ISCAP_GUI(app, 'Inter-Subject Coactivation Pattern (ISCAP)', 'ISCAP');
            end
            if isequal(value, 'CAP')
                app.CAPapp = ISCAP_GUI(app, 'Coactivation Pattern (CAP)', 'CAP');
            end
            if isequal(value, 'ISC')
                app.ISCapp = ISC_GUI(app);
            end
            if isequal(value, 'SWISC')
                app.ISCapp = SWISC_GUI(app);
            end
        end

        % Button pushed function: ROIMethodButton
        function ROIMethodButtonPushed(app, event)
            value = app.ROIMethodsDropDown.Value;
            
            if ~isequal(value, ' ')
                app.ROIMethodsDropDown.Enable = "off";
                app.ROIMethodButton.Enable = "off";
            else
                return
            end
            
            if isequal(value, 'SFC')
                app.SFCapp = SFCROI_GUI(app, 'Static Functional Connectivity (SFC)', 'SFC');
                return
            end
            if isequal(value, 'ISFC')
                app.SFCapp = SFCROI_GUI(app, 'Inter-Subject Functional Connectivity (ISFC)', 'ISFC');
                return
            end
            if isequal(value, 'SWFC')
                app.SWFCapp = ISSWFC_GUI(app, 'Sliding-Window Functional Connectivity (SWFC)', 'SWFC');
                return
            end
            if isequal(value, 'ISSWFC')
                app.ISSWFCapp = ISSWFC_GUI(app, 'Inter-Subject Sliding-Window Functional Connectivity (ISSWFC)', 'ISSWFC');
                return
            end
            if isequal(value, 'DCC')
                app.DCCapp = ISDCC_GUI(app, 'Dynamic Conditional Correlation (DCC)', 'DCC');
                return
            end
            if isequal(value, 'ISDCC')
                app.ISDCCapp = ISDCC_GUI(app, 'Inter-Subject Dynamic Conditional Correlation (ISDCC)', 'ISDCC');
                return
            end
            if isequal(value, 'FLS')
                app.FLSapp = ISFLS_GUI(app, 'Flexible Least Square (FLS)', 'FLS');
                return
            end
            if isequal(value, 'ISFLS')
                app.ISFLSapp = ISFLS_GUI(app, 'Inter-Subject Flexible Least Square (ISFLS)', 'ISFLS');
                return
            end
            if isequal(value, 'GLKF')
                app.GLKFapp = ISGLKF_GUI(app, 'General Linear Kalman Filter (GLKF)', 'GLKF');
                return
            end
            if isequal(value, 'ISGLKF')
                app.ISGLKFapp = ISGLKF_GUI(app, 'Inter-Subject General Linear Kalman Filter (ISGLKF)', 'ISGLKF');
                return
            end
            if isequal(value, 'MTD')
                app.MTDapp = ISMTD_GUI(app, 'Multiplication of Temporal Derivatives (MTD)', 'MTD');
                return
            end
            if isequal(value, 'ISMTD')
                app.ISMTDapp = ISMTD_GUI(app, 'Inter-Subject Multiplication of Temporal Derivatives (ISMTD)', 'ISMTD');
                return
            end
        end

        % Button pushed function: ClusteringPlottingButton
        function ClusteringPlottingButtonPushed(app, event)
            app.ClusteringPlottingButton.Enable = 'off';
            app.Cluster_Plotapp = Cluster_Plotting_GUI(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NaDyNetUIFigure = uifigure;
            app.NaDyNetUIFigure.Name = 'NaDyNet';
            app.NaDyNetUIFigure.CloseRequestFcn = createCallbackFcn(app, @NaDyNetUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.NaDyNetUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.NaDyNetUIFigure);
            app.ExtractROITimeCourseButton = uibutton(app.Panel, 'push');
            app.ExtractROITimeCourseButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROITimeCourseButtonPushed, true);
            app.ExtractROITimeCourseButton.Text = 'Extract ROI Time Course';
            app.STEP1ROITimeCourseExtractionLabel = uilabel(app.Panel);
            app.STEP1ROITimeCourseExtractionLabel.Text = 'STEP 1: ROI Time Course Extraction';
            app.Panel_2 = uipanel(app.NaDyNetUIFigure);
            app.STEP2MethodSelectionLabel = uilabel(app.Panel_2);
            app.STEP2MethodSelectionLabel.Text = 'STEP 2: Method Selection';
            app.ROIMethodsDropDownLabel = uilabel(app.Panel_2);
            app.ROIMethodsDropDownLabel.Text = 'ROI Methods:';
            app.ROIMethodsDropDown = uidropdown(app.Panel_2);
            app.ROIMethodsDropDown.Items = {'ISFC', 'SFC', 'ISSWFC', 'SWFC', 'ISDCC', 'DCC', 'ISFLS', 'FLS', 'ISGLKF', 'GLKF', 'ISMTD', 'MTD', ' '};
            app.ROIMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIMethodsDropDownValueChanged, true);
            app.ROIMethodsDropDown.Value = ' ';
            app.GMVoxelMethodsDropDownLabel = uilabel(app.Panel_2);
            app.GMVoxelMethodsDropDownLabel.Text = 'GM Voxel Methods:';
            app.GMVoxelMethodsDropDown = uidropdown(app.Panel_2);
            app.GMVoxelMethodsDropDown.Items = {'ISC', 'SWISC', 'ISCAP', 'CAP', ' '};
            app.GMVoxelMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @GMVoxelMethodsDropDownValueChanged, true);
            app.GMVoxelMethodsDropDown.Value = ' ';
            app.ROIMethodButton = uibutton(app.Panel_2, 'push');
            app.ROIMethodButton.ButtonPushedFcn = createCallbackFcn(app, @ROIMethodButtonPushed, true);
            app.ROIMethodButton.Text = 'choose';
            app.GMMethodButton = uibutton(app.Panel_2, 'push');
            app.GMMethodButton.ButtonPushedFcn = createCallbackFcn(app, @GMMethodButtonPushed, true);
            app.GMMethodButton.Text = 'choose';
            app.Panel_3 = uipanel(app.NaDyNetUIFigure);
            app.ClusteringPlottingButton = uibutton(app.Panel_3, 'push');
            app.ClusteringPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @ClusteringPlottingButtonPushed, true);
            app.ClusteringPlottingButton.Text = 'Clustering & Plotting';
            app.STEP3ClusteringPlottingLabel = uilabel(app.Panel_3);
            app.STEP3ClusteringPlottingLabel.Text = 'STEP 3:  Clustering & Plotting';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NDN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NaDyNetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NaDyNetUIFigure)
        end
    end
end